classdef RbSrappTest_autoreflowv17 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MADRbSr                         matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        yinterceptuncertaintyEditField_2  matlab.ui.control.NumericEditField
        LoaddataButton                  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        MSWDLabel                       matlab.ui.control.Label
        MSWDEditField                   matlab.ui.control.NumericEditField
        InputfilenamexlsxEditFieldLabel  matlab.ui.control.Label
        InputfilenamexlsxEditField      matlab.ui.control.EditField
        yinterceptuncertaintyEditField_3  matlab.ui.control.NumericEditField
        yinterceptLabel                 matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        ClearselectionButton            matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        IterationmethodLabel            matlab.ui.control.Label
        DetritalDAuthigenicAmodelLabel  matlab.ui.control.Label
        RadiogenicrangeLabel            matlab.ui.control.Label
        StratigraphicageLabel           matlab.ui.control.Label
        ageuncertaintyMaEditFieldLabel  matlab.ui.control.Label
        ageuncertaintyMaEditField       matlab.ui.control.NumericEditField
        iterations95thpercentileTextArea  matlab.ui.control.TextArea
        iterations95thpercentileTextAreaLabel  matlab.ui.control.Label
        modelafterSwitch                matlab.ui.control.Switch
        modelafterSwitchLabel           matlab.ui.control.Label
        numberofiterationsEditField     matlab.ui.control.NumericEditField
        numberofiterationsLabel         matlab.ui.control.Label
        leastradiogenicEditField_2      matlab.ui.control.NumericEditField
        leastradiogenicLabel            matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        mostradiogenicLabel             matlab.ui.control.Label
        mostradiogenicEditField         matlab.ui.control.NumericEditField
        stratigraphicageMaLabel         matlab.ui.control.Label
        ageMaEditField                  matlab.ui.control.NumericEditField
        ClearButton                     matlab.ui.control.Button
        optimizeiterationSwitch         matlab.ui.control.Switch
        optimizeiterationSwitchLabel    matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
        % Stores users path and filename of the selected file that includes
        % the measured data
        filechosen
        
        % Measured data from user's file
        Rb_Sr_measured   % RbSr
        Sr_Sr_measured   % SrSr                  
        Rb_Sr_measured_err  % y_err   % SrSr 2SE
        Sr_Sr_measured_err  % x_err   % RbSr 2SE
        rho
       
        % Data overview
        b               % is the slope of the regression of the measured data input
        yCalc2          % results of y= m*x
        age_reg         % model age results based on the regression
        age_reg_err
        
        SelectedCells   %Rows selected in the table to remove
        selectedCell    %Selected cells in table
        row             %selected rows in table
        A_sum_ft_old
        A_sum_ft_old_selected
        x_selected
        x_err_selected
        y_selected
        y_err_selected
        rho_selected
        value_yintercept
        
        allages
        allages_err
        
        s
        mswd
        mage
        mage_err
        
        greater_stratage
        mage1_err
        iterationstat
    end
    
    properties (Access = private)
        Oldfile % Description
        Newfile % Description
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MADRbSr.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {912, 912};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {643, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
%%%%%%%%%%% CLEAR ALL %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
            %Everytime you load new data all panels within the UIFigure
            %clears their content and when select a new file it plots the
            %new data in the "data overview" panels
            %clear Rb-Sr plot
            cla(app.UIAxes);
            %set y-intercept value and MSWD value to zero
            app.yinterceptuncertaintyEditField_3.Value= 0;
            app.MSWDEditField.Value= 0;
            %clear the data table
            app.UITable.Data= [];
            %clear the probability density plot
            cla(app.UIAxes2);
            
%%%%%%%%%%% LOAD THE FILE %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            %user selects excel file .xlsx in there folderpath
            %store filename and path in variables
            [filename, path] = uigetfile("*.xlsx");
            %show filename in the app field
            app.InputfilenamexlsxEditField.Value= filename;
            %set up "data overview" figure
            figure(app.MADRbSr);
            app.filechosen= [path filename];
            
%%%%%%%%%%% READ THE DATA %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            %Get data from the file
            data = xlsread(app.filechosen);
            %assign columns to global variables
            %Rb/Sr data
            app.Rb_Sr_measured = data(:,1);
            %Sr/Sr data
            app.Sr_Sr_measured= data(:,3);
            %Rb/Sr uncertainty data
            app.Rb_Sr_measured_err= data(:,2);
            %Sr/Sr uncertainty data
            app.Sr_Sr_measured_err= data(:,4);
            %Rho
            app.rho= data(:,5);
            
%%%%%%%%%%% DATA PROCESSING %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            %Regression
            %get the yorkfit function from the function file 'fcns_RbSrapp' yorkfit = [slope_calc; intercept_calc; intercept_uncertainty; MSWD_all; ini]; 
            H= fcns_RbSrapp.york(app.Rb_Sr_measured,app.Rb_Sr_measured_err,app.Sr_Sr_measured,app.Sr_Sr_measured_err,app.rho);
            app.b= H; 
            %get the y values of each data point, this equals y= m*x+c
            app.yCalc2= H(1)*app.Rb_Sr_measured+H(2);
            %show y-intercept in app field
            app.yinterceptuncertaintyEditField_3.Value= app.b(2);
            %show y-intercept uncertainty in app field
            app.yinterceptuncertaintyEditField_2.Value= app.b(3);
            %show MSWD
            app.MSWDEditField.Value = app.b(4);
            
            %Age based on regresseion y-intercept
            %Half-time parameter (Ma) by Steiger and Jäger 1977
            %lambda = 1.42*10^-5;
            %Half-time parameter (Ma) by Gargi, 2012; Chemie der Erde 72, 323–332
            % lambda = 1.408*10^-5;
            %Half-time parameter (Ma) by Villa et al. 2015, lambda = 1.3972 ± 0.0045 *10^-5;
            lambda = 1.3972*10^-5;
            
            %Age calculation 
            % b(2) is the y-intercept of the regression line
            z= app.Sr_Sr_measured-app.b(2);
%             z_plus_err= (app.Sr_Sr_measured+app.Sr_Sr_measured_err)-app.b(2);
            app.age_reg= (1/lambda)*log(1+z./app.Rb_Sr_measured);
            
            %Age error calculation
            % Partial derivatives
            partial_t_D = 1 ./ (lambda * (z + app.Rb_Sr_measured));
            partial_t_P = -z ./ (lambda * app.Rb_Sr_measured .* (z + app.Rb_Sr_measured));
            partial_t_I = -1/lambda * (1./(app.Rb_Sr_measured + z));
            % Error propagation,,
            sigma_t_squared = (partial_t_D.^2 .* app.Sr_Sr_measured_err.^2) + (partial_t_P.^2 .* app.Rb_Sr_measured_err.^2) + ...
                2 .*partial_t_D.*partial_t_P.*app.rho.*app.Sr_Sr_measured_err.*app.Rb_Sr_measured_err + partial_t_I .* app.b(3) ;
            sigma_t = sqrt(sigma_t_squared);
            app.age_reg_err= sigma_t;
%             age_reg_pluserr= (1/lambda)*log(1+z_plus_err./(app.Rb_Sr_measured+app.Rb_Sr_measured_err));
%             age_reg_err_s= age_reg_pluserr - app.age_reg;
%             app.age_reg_err= abs(age_reg_err_s);
            %disp(app.age_reg);
            %disp(app.age_reg_err);
            
            %Calculation for the probability density plot
            %Plot the probability density of the modelages calculated based
            %on the regression line
            % t represents the x-axis values [Ma]
            t= 0:1:4500;
            % steps is the x-axis plotting point, every 1Myr
            steps= 1;
            A_sum_ft= fcns_RbSrapp.pdp(t,steps,app.age_reg,app.age_reg_err);
            app.A_sum_ft_old= A_sum_ft;
            %disp(A_sum_ft);
            
%%%%%%%%%%% PLOT (RB-SR GRAPH, TABLE, PROPABILITY) %%%%%%%%%%%%%%%%%%%%%%%%
            %Plot data
            plot(app.UIAxes,app.Rb_Sr_measured,app.Sr_Sr_measured, "b.");
            hold (app.UIAxes, 'on')
            %Plot errorellipse
            errell= fcns_RbSrapp.ell(app.Rb_Sr_measured, app.Rb_Sr_measured_err, app.Sr_Sr_measured, app.Sr_Sr_measured_err, app.rho);
            X = errell(:, 1:2:end); % Get every other column for X
            Y = errell(:, 2:2:end); % Similar for Y
            plot(app.UIAxes,X,Y,'-', "Color","#80B3FF");
            
            %Plot the regression line
            plot(app.UIAxes,app.Rb_Sr_measured,app.yCalc2,'k-');
            %hold (app.UIAxes, 'off')
            
            %Add values into the table
            app.UITable.Data= [data(:,1),data(:,2),data(:,3),data(:,4),data(:,5),app.age_reg,app.age_reg_err];
            app.UITable.ColumnName= {'Rb/Sr';'Rb/Sr_err';'Sr/Sr';'Sr/Sr_err';'rho';'age(Ma)';'age_err(Ma)'};
            app.UITable.ColumnEditable = [false false false false false false false];
            app.UITable.ColumnFormat= {'numeric','numeric','numeric','numeric','numeric','bank','bank'};
            
            %Plot the probability density of the modelages calculated based
            %on the regression line
            plot(app.UIAxes2,t,A_sum_ft,'b-');
            %grid(app.UIAxes2,"on")
            hold(app.UIAxes2,'on')
            
        end

        % Callback function
        function yinterceptEditFieldValueChanged(app, event)
            app.b(2) = app.yinterceptuncertaintyEditField_3.Value;
        end

        % Value changed function: yinterceptuncertaintyEditField_2
        function yinterceptuncertaintyEditField_2ValueChanged(app, event)
            app.b(3) = app.yinterceptuncertaintyEditField_2.Value;
        end

        % Value changed function: MSWDEditField
        function MSWDEditFieldValueChanged(app, event)
            app.b(4) = app.MSWDEditField.Value;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            %User can select rows which will not be used in the
            %regressionline- and the age-calculation
            %get indices of selected cells
            app.selectedCell = event.Indices;
            %get the row index
            app.row = [app.row event.Indices(1)];
            %change the background color of the selected row
            app.s = uistyle('BackgroundColor',[0.6 0.6 0.6]);
            addStyle(app.UITable,app.s,'row', app.row);
            %get the Data of the selected rows and change their color in
            %the data overview figure to display that they will not be used
            %in the further calculations
            app.x_selected= app.UITable.Data(app.row,1);
            app.x_err_selected= app.UITable.Data(app.row,2);
            app.y_selected= app.UITable.Data(app.row,3);
            app.y_err_selected= app.UITable.Data(app.row,4);
            app.rho_selected= app.UITable.Data(app.row,5);
            %marker color changes
            errell= fcns_RbSrapp.ell(app.x_selected,app.x_err_selected, app.y_selected,app.y_err_selected,app.rho_selected);
            X = errell(:, 1:2:end); % Get every other column for X
            Y = errell(:, 2:2:end); % Similar for Y
            ss=plot(app.UIAxes,X,Y,'k-');
            alpha(ss,.5);
            ss=plot(app.UIAxes,app.x_selected,app.y_selected, "k.");
            alpha(ss,.5);
            %age column of the selected rows will show -9999 to express
            %that their age will be not calculated based on the new
            %regression line
            app.UITable.Data(app.row,6)= -9999;
            app.UITable.Data(app.row,7)= -9999;

        end

        % Button pushed function: ClearselectionButton
        function ClearselectionButtonPushed(app, event)
%%%%%%%%%%% CLEAR ALL %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
            %clear Rb-Sr plot
            cla(app.UIAxes);
            %clear Table
            removeStyle(app.UITable)
            app.UITable.Data= [];
            app.selectedCell = [];
            app.row =[];
            %clear probability density plot
            cla(app.UIAxes2);
            
%%%%%%%%%%% GET ORIGINAL DATA %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            %add the original data again
            data = xlsread(app.filechosen);
            app.Rb_Sr_measured = data(:,1);
            app.Sr_Sr_measured= data(:,3);
            app.Sr_Sr_measured_err= data(:,4);
            app.Rb_Sr_measured_err= data(:,2);
            app.rho= data(:,5);
           
%%%%%%%%%%% DATA PROCESSING %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            %Regression
            %get the yorkfit function from the function file 'fcns_RbSrapp'
            H= fcns_RbSrapp.york(app.Rb_Sr_measured,app.Rb_Sr_measured_err,app.Sr_Sr_measured,app.Sr_Sr_measured_err,app.rho);
            app.b= H; 
            %get the y values of each data point, this equals y= m*x+c
            app.yCalc2= H(1)*app.Rb_Sr_measured+H(2);
            %show y-intercept in app field
            app.yinterceptuncertaintyEditField_3.Value= app.b(2);
            %show MSWD
            app.MSWDEditField.Value = app.b(3);
            
            %Age based on regresseion y-intercept
            %Half-time parameter
            lambda = 1.3972*10^-5;
            
            %Age calculation
            z= app.Sr_Sr_measured-app.b(2);
            app.age_reg= (1/lambda)*log(1+z./app.Rb_Sr_measured);   
            %Age error calculation
            % Partial derivatives
            partial_t_D = 1 ./ (lambda * (z + app.Rb_Sr_measured));
            partial_t_P = -z ./ (lambda * app.Rb_Sr_measured .* (z + app.Rb_Sr_measured));
            % Error propagation
            sigma_t_squared = (partial_t_D.^2 .* app.Sr_Sr_measured_err.^2) + (partial_t_P.^2 .* app.Rb_Sr_measured_err.^2) + 2 .*partial_t_D.*partial_t_P.*app.rho.*app.Sr_Sr_measured_err.*app.Rb_Sr_measured_err;
            sigma_t = sqrt(sigma_t_squared);
            app.age_reg_err= sigma_t;
            
            %Calculation for the probability density plot
            %Plot the probability density of the modelages calculated based
            %on the regression line
            % t represents the x-axis values [Ma]
            t= 0:1:4500;
            % steps is the x-axis plotting point, every 1Myr
            steps= 1;
            A_sum_ft= fcns_RbSrapp.pdp(t,steps,app.age_reg,app.age_reg_err);
            %disp(A_sum_ft);
            
%%%%%%%%%%% PLOT (RB-SR GRAPH, TABLE, PROPABILITY) %%%%%%%%%%%%%%%%%%%%%%%%
            %Plot data
            plot(app.UIAxes,app.Rb_Sr_measured,app.Sr_Sr_measured, "b.");
            hold (app.UIAxes, 'on')
            %Plot errorellipse
            errell= fcns_RbSrapp.ell(app.Rb_Sr_measured, app.Rb_Sr_measured_err, app.Sr_Sr_measured, app.Sr_Sr_measured_err, app.rho);
            X = errell(:, 1:2:end); % Get every other column for X
            Y = errell(:, 2:2:end); % Similar for Y
            plot(app.UIAxes,X,Y,'-', "Color","#80B3FF");
            
            %Plot the regression line
            plot(app.UIAxes,app.Rb_Sr_measured,app.yCalc2,'k-');
            %hold (app.UIAxes, 'off')
            
            %Add values into the table
            app.UITable.Data= [data(:,1),data(:,2),data(:,3),data(:,4),data(:,5),app.age_reg,app.age_reg_err];
            app.UITable.ColumnName= {'Rb/Sr';'Rb/Sr_err';'Sr/Sr';'Sr/Sr_err';'rho';'age(Ma)';'age_err(Ma)'};
            app.UITable.ColumnEditable = [false false false false false false false];
            app.UITable.ColumnFormat= {'numeric','numeric','numeric','numeric','numeric','bank','bank'};
            
            %Plot the probability density of the modelages calculated based
            %on the regression line
            plot(app.UIAxes2,t,A_sum_ft,'b-');
            %grid(app.UIAxes2,"on")
            hold(app.UIAxes2,'on')
       
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
%%%%%%%%%%%% REMOVE SELECTION %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
            %user updates the data table based on the row selection which 
            % excudes the selected data from further calculations
            %grey out old age distribution
            t= 0:1:4500;
            plot(app.UIAxes2,t,app.A_sum_ft_old,"-","Color",[0.5 0.5 0.5]);
            %define the new data
            data_new = app.UITable.Data;
            %empty the selected rows
            data_new(app.row,:) = [];
            cla(app.UIAxes);
            %clear probability density plot
            %cla(app.UIAxes2);
            
%%%%%%%%%%% GET DATA FROM THE UPDATED DATA %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            %Update figure and table
            %set new column variables
            x_new= data_new(:,1);
            x_err_new= data_new(:,2);
            y_new= data_new(:,3);
            y_err_new= data_new(:,4);
            rho_new= data_new(:,5);
            
            
%%%%%%%%%%% DATA PROCESSING %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            wb = waitbar(0,'Processing...');
            %Regression
            %get the yorkfit function from the function file 'fcns_RbSrapp'
            H_new= fcns_RbSrapp.york(x_new,x_err_new,y_new,y_err_new,rho_new);
            b_new= H_new; 
            %get the y values of each data point, this equals y= m*x+c
            yCalc2_new= b_new(1)*x_new+b_new(2);
            %show y-intercept in app field
            app.yinterceptuncertaintyEditField_3.Value= b_new(2);
            %show MSWD
            app.MSWDEditField.Value = b_new(3);            
            pause(0.5);
            %Age caluclation
            %Update age in table based on the new y-intercept
            %age based on regresseion y-intercept
            lambda = 1.3972*10^-5;
            i=1;
            while (i <= length(app.Rb_Sr_measured))
                if app.UITable.Data(i,6)~= -9999
                    z_new= app.Sr_Sr_measured(i)-b_new(2);
                    %z_plus_new_err= (app.Sr_Sr_measured(i)+app.Sr_Sr_measured_err(i))-b_new(2);
                    age_reg_new= (1/lambda)*log(1+z_new./app.Rb_Sr_measured(i));  
                    %Age error calculation
                    % Partial derivatives
                    partial_t_D_new = 1 ./ (lambda * (z_new + app.Rb_Sr_measured(i)));
                    partial_t_P_new = -z_new ./ (lambda * app.Rb_Sr_measured(i) .* (z_new + app.Rb_Sr_measured(i)));
                    % Error propagation
                    sigma_t_squared_new = (partial_t_D_new.^2 .* app.Sr_Sr_measured_err(i).^2) + (partial_t_P_new.^2 .* app.Rb_Sr_measured_err(i).^2) + 2 .*partial_t_D_new.*partial_t_P_new.*app.rho(i).*app.Sr_Sr_measured_err(i).*app.Rb_Sr_measured_err(i);
                    sigma_t_new = sqrt(sigma_t_squared_new);
                    age_reg_new_err= sigma_t_new;
                     %update table
                    app.UITable.Data(i,6)= age_reg_new;
                    app.UITable.Data(i,7)= age_reg_new_err;
                    i= i+1;
                else
                    %keep table values (selected -999.00)
                    app.UITable.Data(i,6) = app.UITable.Data(i,6);
                    app.UITable.Data(i,7) = app.UITable.Data(i,7);
                    i= i+1;
                end   
            end
            pause(0.5);
            %Calculate ages again without the selections for the
            %probability density plot
            z_new= y_new-b_new(2);
            %z_plus_new_err= (y_new+y_err_new)-b_new(2);
            age_reg_new= (1/lambda)*log(1+z_new./x_new);
            %Age error calculation
            % Partial derivatives
            partial_t_D_new = 1 ./ (lambda * (z_new + x_new));
            partial_t_P_new = -z_new ./ (lambda * x_new .* (z_new + x_new));
            % Error propagation
            sigma_t_squared_new = (partial_t_D_new.^2 .* y_err_new.^2) + (partial_t_PNew.^2 .* x_err_new.^2) + 2 .*partial_t_D_new.*partial_t_P_new.*rho_new.*y_err_new.*x_err_new;
            sigma_t_new = sqrt(sigma_t_squared_new);
            age_reg_new_err= sigma_t_new;
            
            
%%%%%%%%%%% PLOT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            %Update Plot
            plot(app.UIAxes,x_new,y_new, "b.");
            hold (app.UIAxes, 'on')
            %Plot errorellipse
            errell= fcns_RbSrapp.ell(x_new, x_err_new, y_new, y_err_new, rho_new);
            X = errell(:, 1:2:end); % Get every other column for X
            Y = errell(:, 2:2:end); % Similar for Y
            plot(app.UIAxes,X,Y,'-', "Color","#80B3FF");
            hold (app.UIAxes, 'on')
            errell= fcns_RbSrapp.ell(app.x_selected,app.x_err_selected, app.y_selected,app.y_err_selected,app.rho_selected);
            X = errell(:, 1:2:end); % Get every other column for X
            Y = errell(:, 2:2:end); % Similar for Y
            plot(app.UIAxes,X,Y,'-', "Color",[0.5 0.5 0.5]);
            hold (app.UIAxes, 'on')
            plot(app.UIAxes,app.x_selected,app.y_selected, ".","Color",[0.5 0.5 0.5]);
            pause(0.5);
%             xlim(app.UIAxes,[min(x_new)-max(x_err_new) max(x_new)+max(x_err_new)]);
%             ylim(app.UIAxes,[min(y_new)-max(y_err_new) max(y_new)+max(y_err_new)]);                       

            %Plot the probability density of the modelages calculated based
            %on the regression line
            % t represents the x-axis values [Ma]
            t= 0:1:4500;
            % steps is the x-axis plotting point, every 1Myr
            steps= 1;
            A_sum_ft= fcns_RbSrapp.pdp(t,steps,age_reg_new,age_reg_new_err);
            % PLot PDP model ages along t values
%             yyaxis(app.UIAxes2,'right')
%             histogram(app.UIAxes2, app.age_reg);
%             yyaxis(app.UIAxes2,'left')
            plot(app.UIAxes2,t,A_sum_ft,'b-');
            %grid(app.UIAxes2,"on")
            app.A_sum_ft_old=A_sum_ft;
            plot(app.UIAxes,x_new,yCalc2_new,'k-')   
            pause(0.5);
%%%%%%%%%%% CHANGE DATA NAME FOR MODELING %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %put the unselected data into the original vector name to use
            %them for further modelling
            %Rb/Sr data
            app.Rb_Sr_measured = x_new;
            %Sr/Sr data
            app.Sr_Sr_measured= y_new;
            %Rb/Sr uncertainty data
            app.Rb_Sr_measured_err= x_err_new;
            %Sr/Sr uncertainty data
            app.Sr_Sr_measured_err= y_err_new;
            app.rho=rho_new;
            delete(wb); 
        end

        % Value changed function: optimizeiterationSwitch
        function optimizeiterationSwitchValueChanged(app, event)
            value = app.optimizeiterationSwitch.Value;
            if ( strcmpi(value,'On') )
                  app.numberofiterationsEditField.Value = 999;
                  app.numberofiterationsEditField.Enable = 'Off';
                  app.iterations95thpercentileTextArea.Enable = 'On';
                  %disp(value)
            else
                  app.numberofiterationsEditField.Enable = 'On';
                  app.iterations95thpercentileTextArea.Enable = 'Off';
                  %disp(value)
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % User input need to be higher than 0
            % If user input is 0 or negative display an error message
            if (app.leastradiogenicEditField_2.Value ~= 0) || (app.mostradiogenicEditField.Value ~= 0) || (app.ageMaEditField.Value ~= 0) || (app.numberofiterationsEditField.Value ~= 0)
                disp(app.numberofiterationsEditField.Value)
                % If user input is not equal 0 start processing the data
                % Show processbar
                wb = waitbar(0,'Processing...');
                
%%%%%%%%%%%%%%% PARAMETER & USER INPUT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Overall Parameter used amd user input assigned to
                % parameters
                % 87 Rb decay constant
                lambda = 1.3972*10^-5;
                least_radiogenic = app.leastradiogenicEditField_2.Value;
                most_radiogenic = app.mostradiogenicEditField.Value;
                strat_age = app.ageMaEditField.Value;
                strat_age_err = app.ageuncertaintyMaEditField.Value;
                least_most_radiogenic= least_radiogenic:0.001:most_radiogenic;
                     
%%%%%%%%%%%%%%% DETRITAL MODEL%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %User chose the detrital model and automated iteration 
                if (strcmpi(app.modelafterSwitch.Value,'D')) && (strcmpi(app.optimizeiterationSwitch.Value,'On')) 
                    %get initial Sr isotope range
                    %Take each value from min to max Sr isotope value in
                    %0.001 steps
                    ini_values = least_radiogenic:0.001:most_radiogenic;
                    %Generate an empty list
                    c_L=[];
                    %initiate while loop and let it run until the length of
                    %Sr isotope vector is reached
                    ik= 1;
                    % Number of measured data points
                    n_measurements = length(app.Rb_Sr_measured);
                    % Initialize matrix for results
                    result_matrix = zeros(n_measurements, length(ini_values)); % First 2 columns for measured values, others for ages
                    uncertainty_matrix = zeros(n_measurements, length(ini_values)); % Matrix for age uncertainties

                    while ik<=length(ini_values)
                        %Age equation
                        z= app.Sr_Sr_measured-ini_values(ik);
                        ages_measurement= (1/lambda)*log(1+ z./app.Rb_Sr_measured);
                        % Store ages in the corresponding column (starting from column 3)
                        result_matrix(:, ik) = ages_measurement;
                        %Age error calculation
                        % Partial derivatives
                        partial_t_D = 1 ./ (lambda * (z + app.Rb_Sr_measured));
                        partial_t_P = -z ./ (lambda * app.Rb_Sr_measured .* (z + app.Rb_Sr_measured));
                        % Error propagation
                        sigma_t_squared = (partial_t_D.^2 .* app.Sr_Sr_measured_err.^2) + (partial_t_P.^2 .* app.Rb_Sr_measured_err.^2) + 2 .*partial_t_D.*partial_t_P.*app.rho.*app.Sr_Sr_measured_err.*app.Rb_Sr_measured_err;
                        sigma_t = sqrt(sigma_t_squared);
                        uncertainty_matrix(:, ik) = sigma_t; % Store uncertainties
%                         %disp(ages_measurement);
%                         greater_strat= ages_measurement>strat_age;
%                         c= sum(greater_strat(:)==1);
%                         c_L= [c_L; c];
                        ik= ik+1;
                    end
                    % Display the result matrix
                    disp('Result Matrix (Rb_Sr, Sr_Sr, Ages):');
                    disp(result_matrix);
                    
                    % Now create a matrix for coloring (1 if age > strat_age, 0 otherwise)
                    strat_age_with_err = strat_age-strat_age_err;
                    color_matrix = result_matrix(:, 1:end) > strat_age_with_err;

                    % Filter out ages with uncertainty > 10% of strat_age for probability calculation
%                     uncertainty_threshold = 0.1 * strat_age; % 10% of stratigraphic age

                    % Initialize a matrix to track valid ages (1 if the age is considered, 0 otherwise)
%                     valid_ages_matrix = uncertainty_matrix <= uncertainty_threshold;

                    % Update the color_matrix to exclude ages with high uncertainty
%                     filtered_color_matrix = color_matrix & valid_ages_matrix; % Only consider valid ages for probability

                    % Calculate probabilities of ages being greater than strat_age for each ini_value
                    probabilities_u = sum(color_matrix, 1) / n_measurements; % Unfiltered probabilities
                    probabilities_unfiltered= probabilities_u./sum(probabilities_u);
%                     probabilities_f = sum(filtered_color_matrix, 1) ./ sum(valid_ages_matrix, 1); % Proportion of valid ages > strat_age
%                     probabilities_filtered= probabilities_f./sum(probabilities_f);

                    % Plot the result matrix with color coding and uncertainty matrix below it
                    figure;
                    
                    % Plot heatmap for age comparison (first matrix)
                    subplot(3, 1, 1); % First subplot for heatmap
                    imagesc(ini_values, 1:n_measurements, color_matrix); % Use unsorted matrix
                    colormap([1 0 0 ; 0 1 0]); % Red for ages > strat_age, green otherwise
                    colorbar;
                    xlabel('Initial Sr/Sr Values)');
                    ylabel('Measurement Index');
                    title('Ages Greater than Stratigraphic Age');


                    % Plot heatmap for age uncertainties (second matrix)
                    subplot(3, 1, 2); % Second subplot for uncertainty heatmap
                    imagesc(ini_values, 1:n_measurements, uncertainty_matrix); % Use unsorted uncertainty matrix
                    
                    % Create custom colormap from dark green to dark red
                    n_colors = 256; % Number of color steps
                    custom_colormap = [linspace(0, 1, n_colors)', linspace(1, 0, n_colors)', linspace(0, 0, n_colors)']; % Dark green to dark red
                    
                    % Apply the custom colormap and display the colorbar
                    colormap(gca, custom_colormap);
                    colorbar;
                    
                    % Set the color limits to correspond to the min and max uncertainty values
                    % Get min and max uncertainty values
                    min_uncertainty = min(uncertainty_matrix(:));
                    max_uncertainty = max(uncertainty_matrix(:));
                    caxis([min_uncertainty max_uncertainty]);
                    
                    % Add labels and title for uncertainty heatmap
                    xlabel('Initial Sr/Sr Values');
                    ylabel('Measurement Index');
                    title('Age Uncertainties');
                    
                    % Add colorbar label for min and max uncertainty values
                    cb = colorbar;
                    ylabel(cb, 'age-err (Ma)'); % Label for the colorbar
                    cb.Ticks = [min_uncertainty max_uncertainty]; % Set the ticks to show only min and max
                    cb.TickLabels = {num2str(min_uncertainty), num2str(max_uncertainty)}; % Display actual min/max values
                    
                    % Plot the probabilities in a new subplot (third subplot)
                    subplot(3, 1, 3); % Third subplot for probabilities
                    bar(ini_values, probabilities_unfiltered, 'FaceColor', 'blue', 'EdgeColor', 'none');
                    hold on;

                    % Plot the filtered probabilities as a transparent bar
%                     bar(ini_values, probabilities_filtered, 'FaceColor', 'red', 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                    
                    xlabel('Initial Sr/Sr Values');
                    ylabel('Probability of Age > Strat Age');
                    title('Probability of Ages Greater than Stratigraphic Age');
                    %ylim([0, 1]); % Set y-axis limit to reflect probability scale (0 to 1)
                    %legend({'Unfiltered'});
%                     legend({'Unfiltered', 'Filtered (age-err <= 5% strat age)'});
                    hold off;

%                     %determine absoulte probability of Sr/Sr initials
%                     total= sum(c_L);
%                     p_c_L= c_L./total;
                    
                    plot(app.UIAxes3,least_most_radiogenic,probabilities_unfiltered,'r-')
                    drawnow
                    pause(0.5);
                %%%%%%%%%%%%%%% NUMBER OF ITERATIONS - STATISTICAL APPROACH %%%%%%%%%%%%%%%                
                    %absolute probability of Sr/Sr initials      
                    prob_ini_allowed_L= probabilities_unfiltered;
                    least_most_radiogenic= least_most_radiogenic.';
                    
                    % Initialize variables
                    max_iterations = length(least_most_radiogenic);
                    critical_value_factor = 1.36;
                    p_value_threshold = 0.05; % 95% confidence level
                    
                    % Initialize storage
                    all_ages1 = [];
                    all_ages2 = [];
                    iteration_counts = [];
                    
                    % Loop until p-value exceeds critical value
                    p_value = 1;
                    critical_value = 2;
                        while p_value < critical_value
                            % Run first batch of simulations
                            [ages1, age_errs1] = fcns_RbSrapp.calculateModelAges(max_iterations, lambda, prob_ini_allowed_L, least_most_radiogenic,app.rho,app.Rb_Sr_measured,app.Rb_Sr_measured_err,app.Sr_Sr_measured, app.Sr_Sr_measured_err);
                            
                            % Run second batch of simulations
                            [ages2, age_errs2] = fcns_RbSrapp.calculateModelAges(max_iterations, lambda, prob_ini_allowed_L, least_most_radiogenic,app.rho,app.Rb_Sr_measured,app.Rb_Sr_measured_err,app.Sr_Sr_measured, app.Sr_Sr_measured_err);
                            
                            % Combine results
                            all_ages1 = [all_ages1; [ages1, age_errs1]];
                            all_ages2 = [all_ages2; [ages2, age_errs2]];
                            
                            % Perform Kolmogorov-Smirnov test
                            [~, p_value, ~] = kstest2(all_ages1(:, 1), all_ages2(:, 1));
                            
                            % Calculate critical value
                            num_ages1 = length(all_ages1(:, 1));
                            num_ages2 = length(all_ages2(:, 1));
                            critical_value = critical_value_factor * sqrt((num_ages1 + num_ages2) / (num_ages1 * num_ages2));
                            critical_value = critical_value;
                            p_value = p_value;
                            % Display current iteration results
                            disp(['Critical Value: ', num2str(critical_value)]);
                            disp(['P-Value: ', num2str(p_value)]);
                            
                            % Update iteration count
                            iteration_counts = [iteration_counts; size(all_ages1, 1)];
                        end
                    
                    % Display final iteration count
                    disp(['Number of iterations until p-value exceeded critical value: ', num2str(iteration_counts(end)/length(all_ages2))]);
                    % Remove rows where the age values are negative after concatenation
                    all_ages2 = all_ages2(all_ages2(:, 1) >= 0, :);  % Keep only rows where the age is non-negative
                    app.allages=[];
                    app.allages= all_ages2(:,1);
                    app.allages_err=[];
                    app.allages_err= all_ages2(:,2);
                    % Filter age uncertainty <= 5% strat age
                    %all_ages_err_filtered= all_ages2(all_ages2(:, 2) <= 0.05*strat_age, :);
                    %Filter ages <= stratigraphic age
                    %all_ages_filtered= all_ages2(all_ages2(:, 1) >= strat_age, :);
%                         end
%                         
%                         %how many iterations until p value exceeds critical
%                         %value
%                         R_L= [R_L; runs];
%                         %store the last two age distributionmodelages
%                         Mallages= [Mallages; Mmage3_filtered2; Mmage4_filtered2]; 
%                         app.allages= Mallages(:,1);
%                         app.allages_err= Mallages(:,2);
%                         %set the modelageerrors to 10Ma if they are less
%                         %than 10 Ma
%                         app.allages_err(app.allages_err < 10) = 10;
                      
%                     end
                    pause(0.5);
                    % t represents the x-axis values [Ma]
                    t= 0:1:4500;
                    % steps is the x-axis plotting point, every 1Myr
                    steps= 1;
                    A_sum_ft4= fcns_RbSrapp.pdp(t,steps,app.allages,app.allages_err);
                    %A_sum_ft_filtered_err= fcns_RbSrapp.pdp(t,steps,all_ages_err_filtered(:,1),all_ages_err_filtered(:,2));
                    %A_sum_ft_filtered_age= fcns_RbSrapp.pdp(t,steps,all_ages_filtered(:,1),all_ages_filtered(:,2));
                    % Plot the lines with varying shades of red
                    pdline5 = plot(app.UIAxes4, t, A_sum_ft4, 'Color', [1, 0, 0], 'LineWidth', 1);  % Bright Red
                    hold(app.UIAxes4, 'on');
                    %pdline6 = plot(app.UIAxes4, t, A_sum_ft_filtered_err, 'Color', [0.8, 0, 0], 'LineWidth', 1);  % Darker Red
                    %pdline7 = plot(app.UIAxes4, t, A_sum_ft_filtered_age, 'Color', [0.6, 0, 0], 'LineWidth', 1);  % Even Darker Red
                    xline(app.UIAxes4,strat_age,"k-",{"stratigraphic age"})
                    xlim(app.UIAxes4,[0, 4500]);
                    % Add a legend with descriptions
                    %legend(app.UIAxes4, {'Unfiltered', 'Filtered age-err <= 5% strat age', 'Filtered age > strat age'}, 'Location', 'northeast');
                    drawnow

                    hold(app.UIAxes4,'off')
                    %how often the dataset went through the iteration
                    it= iteration_counts(end)/max_iterations;
                    %Get the 95th percentile of the iterations
                    R_L_percentile= prctile((iteration_counts./max_iterations), 95);
                    %Iteration stats
                    resultstring= num2str(R_L_percentile);
                    %show the value of the 95th percentile in the app
                    %interface
                    app.iterations95thpercentileTextArea.Value = resultstring;
                    
                    delete(wb);
%%%%%%%%%%%%%%% NUMBER OF ITERATIONS - USER INPUT %%%%%%%%%%%%%%%%%%%%%%%%%                
                elseif (strcmpi(app.modelafterSwitch.Value,'D')) && (strcmpi(app.optimizeiterationSwitch.Value,'Off'))
                    %get initial Sr isotope range
                    %Take each value from min to max Sr isotope value in
                    %0.001 steps
                    ini_values = least_radiogenic:0.001:most_radiogenic;
                    %Generate an empty list
                    c_L=[];
                    %initiate while loop and let it run until the length of
                    %Sr isotope vector is reached
                    ik= 1;
                    % Number of measured data points
                    n_measurements = length(app.Rb_Sr_measured);
                    % Initialize matrix for results
                    result_matrix = zeros(n_measurements, length(ini_values)); % First 2 columns for measured values, others for ages
                    uncertainty_matrix = zeros(n_measurements, length(ini_values)); % Matrix for age uncertainties

                    while ik<=length(ini_values)
                        %Age equation
                        z= app.Sr_Sr_measured-ini_values(ik);
                        ages_measurement= (1/lambda)*log(1+ z./app.Rb_Sr_measured);
                        % Store ages in the corresponding column (starting from column 3)
                        result_matrix(:, ik) = ages_measurement;
                        %Age error calculation
                        % Partial derivatives
                        partial_t_D = 1 ./ (lambda * (z + app.Rb_Sr_measured));
                        partial_t_P = -z ./ (lambda * app.Rb_Sr_measured .* (z + app.Rb_Sr_measured));
                        % Error propagation
                        sigma_t_squared = (partial_t_D.^2 .* app.Sr_Sr_measured_err.^2) + (partial_t_P.^2 .* app.Rb_Sr_measured_err.^2) + 2 .*partial_t_D.*partial_t_P.*app.rho.*app.Sr_Sr_measured_err.*app.Rb_Sr_measured_err;
                        sigma_t = sqrt(sigma_t_squared);
                        uncertainty_matrix(:, ik) = sigma_t; % Store uncertainties
%                         %disp(ages_measurement);
%                         greater_strat= ages_measurement>strat_age;
%                         c= sum(greater_strat(:)==1);
%                         c_L= [c_L; c];
                        ik= ik+1;
                    end
                    % Display the result matrix
                    disp('Result Matrix (Rb_Sr, Sr_Sr, Ages):');
                    disp(result_matrix);
                    
                    % Now create a matrix for coloring (1 if age > strat_age, 0 otherwise)
                    strat_age_with_err = strat_age-strat_age_err;
                    color_matrix = result_matrix(:, 1:end) > strat_age_with_err;

                    % Filter out ages with uncertainty > 5% of strat_age for probability calculation
%                     uncertainty_threshold = 0.1 * strat_age; % 5% of stratigraphic age

                    % Initialize a matrix to track valid ages (1 if the age is considered, 0 otherwise)
%                     valid_ages_matrix = uncertainty_matrix <= uncertainty_threshold;

                    % Update the color_matrix to exclude ages with high uncertainty
%                     filtered_color_matrix = color_matrix & valid_ages_matrix; % Only consider valid ages for probability

                    % Calculate probabilities of ages being greater than strat_age for each ini_value
                    probabilities_u = sum(color_matrix, 1) / n_measurements; % Unfiltered probabilities
                    probabilities_unfiltered= probabilities_u./sum(probabilities_u);
%                     probabilities_f = sum(filtered_color_matrix, 1) ./ sum(valid_ages_matrix, 1); % Proportion of valid ages > strat_age
%                     probabilities_filtered= probabilities_f./sum(probabilities_f);

                    % Plot the result matrix with color coding and uncertainty matrix below it
                    figure;
                    
                    % Plot heatmap for age comparison (first matrix)
                    subplot(3, 1, 1); % First subplot for heatmap
                    imagesc(ini_values, 1:n_measurements, color_matrix); % Use unsorted matrix
                    colormap([1 0 0 ; 0 1 0]); % Red for ages > strat_age, green otherwise
                    colorbar;
                    xlabel('Initial Sr/Sr Values)');
                    ylabel('Measurement Index');
                    title('Ages Greater than Stratigraphic Age');
      
                    % Plot heatmap for age uncertainties (second matrix)
                    subplot(3, 1, 2); % Second subplot for uncertainty heatmap
                    imagesc(ini_values, 1:n_measurements, uncertainty_matrix); % Use unsorted uncertainty matrix
                    
                    % Create custom colormap from dark green to dark red
                    n_colors = 256; % Number of color steps
                    custom_colormap = [linspace(0, 1, n_colors)', linspace(1, 0, n_colors)', linspace(0, 0, n_colors)']; % Dark green to dark red
                    
                    % Apply the custom colormap and display the colorbar
                    colormap(gca, custom_colormap);
                    colorbar;
                    
                    % Set the color limits to correspond to the min and max uncertainty values
                    % Get min and max uncertainty values
                    min_uncertainty = min(uncertainty_matrix(:));
                    max_uncertainty = max(uncertainty_matrix(:));
                    caxis([min_uncertainty max_uncertainty]);
                    
                    % Add labels and title for uncertainty heatmap
                    xlabel('Initial Sr/Sr Values');
                    ylabel('Measurement Index');
                    title('Age Uncertainties');
                    
                    % Add colorbar label for min and max uncertainty values
                    cb = colorbar;
                    ylabel(cb, 'age-err (Ma)'); % Label for the colorbar
                    cb.Ticks = [min_uncertainty max_uncertainty]; % Set the ticks to show only min and max
                    cb.TickLabels = {num2str(min_uncertainty), num2str(max_uncertainty)}; % Display actual min/max values
                    
                    % Plot the probabilities in a new subplot (third subplot)
                    subplot(3, 1, 3); % Third subplot for probabilities
                    bar(ini_values, probabilities_unfiltered, 'FaceColor', 'blue', 'EdgeColor', 'none');
                    hold on;

                    % Plot the filtered probabilities as a transparent bar
%                     bar(ini_values, probabilities_filtered, 'FaceColor', 'red', 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                    
                    xlabel('Initial Sr/Sr Values');
                    ylabel('Probability of Age > Strat Age');
                    title('Probability of Ages Greater than Stratigraphic Age');
                    %ylim([0, 1]); % Set y-axis limit to reflect probability scale (0 to 1)
                    %legend({'Unfiltered'});
%                     legend({'Unfiltered', 'Filtered (age-err <= 5% strat age)'});
                    hold off;

%                     %determine absoulte probability of Sr/Sr initials
%                     total= sum(c_L);
%                     p_c_L= c_L./total;
                    
                    plot(app.UIAxes3,least_most_radiogenic,probabilities_unfiltered,'r-')
                    drawnow
                    pause(0.5);
                    %%%%%%%%%%%%%%%%%%
                    %absolute probability of Sr/Sr initials      
                    prob_ini_allowed_L= probabilities_unfiltered;
                    least_most_radiogenic= least_most_radiogenic.';
                    %Get iteration value
                    max_iterations= length(least_most_radiogenic)*app.numberofiterationsEditField.Value;
                    %Apply iteration
                    [ages3, age_errs3] = fcns_RbSrapp.calculateModelAges(max_iterations, lambda, prob_ini_allowed_L, least_most_radiogenic,app.rho,app.Rb_Sr_measured,app.Rb_Sr_measured_err,app.Sr_Sr_measured, app.Sr_Sr_measured_err);
                    % Combine results
                    all_ages3 = [ages3, age_errs3];
                    % Remove rows where the age values are negative after concatenation
                    all_ages3 = all_ages3(all_ages3(:, 1) >= 0, :);  % Keep only rows where the age is non-negative
                    app.allages= all_ages3(:,1);
                    app.allages_err= all_ages3(:,2);
                    % Filter age uncertainty <= 5% strat age
                    %all_ages_err_filtered= all_ages3(all_ages3(:, 2) <= 0.05*strat_age, :);
                    %Filter ages <= stratigraphic age
                    %all_ages_filtered= all_ages3(all_ages3(:, 1) >= strat_age, :);

                    pause(0.5);
                    % t represents the x-axis values [Ma]
                    t= 0:1:4500;
                    % steps is the x-axis plotting point, every 1Myr
                    steps= 1;
                    A_sum_ft4= fcns_RbSrapp.pdp(t,steps,app.allages,app.allages_err);
                    %A_sum_ft_filtered_err= fcns_RbSrapp.pdp(t,steps,all_ages_err_filtered(:,1),all_ages_err_filtered(:,2));
                    %A_sum_ft_filtered_age= fcns_RbSrapp.pdp(t,steps,all_ages_filtered(:,1),all_ages_filtered(:,2));
                    % Plot the lines with varying shades of red
                    pdline5 = plot(app.UIAxes4, t, A_sum_ft4, 'Color', [1, 0, 0], 'LineWidth', 1);  % Bright Red
                    hold(app.UIAxes4, 'on');
                    %pdline6 = plot(app.UIAxes4, t, A_sum_ft_filtered_err, 'Color', [0.8, 0, 0], 'LineWidth', 1);  % Darker Red
                    %pdline7 = plot(app.UIAxes4, t, A_sum_ft_filtered_age, 'Color', [0.6, 0, 0], 'LineWidth', 1);  % Even Darker Red
                    xline(app.UIAxes4,strat_age,"k-",{"stratigraphic age"})
                    xlim(app.UIAxes4,[0, 4500]);
                    % Add a legend with descriptions
                    %legend(app.UIAxes4, {'Unfiltered', 'Filtered age-err <= 5% strat age', 'Filtered age > strat age'}, 'Location', 'northeast');
                    drawnow
                    hold(app.UIAxes4,'off')

                    %Iteration 
                    resultstring= '';
                    %show the value of the 95th percentile in the app
                    %interface
                    app.iterations95thpercentileTextArea.Value = resultstring;
                    
                    delete(wb);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%% AUTHIGENIC %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                elseif (strcmpi(app.modelafterSwitch.Value,'A')) && (strcmpi(app.optimizeiterationSwitch.Value,'On')) 
                                     
                    
                    ini_values = least_radiogenic:0.001:most_radiogenic;
                    %Generate an empty list
                    c_L=[];
                    %initiate while loop and let it run until the length of
                    %Sr isotope vector is reached
                    ik= 1;
                    % Number of measured data points
                    n_measurements = length(app.Rb_Sr_measured);
                    % Initialize matrix for results
                    result_matrix = zeros(n_measurements, length(ini_values)); % First 2 columns for measured values, others for ages
                    uncertainty_matrix = zeros(n_measurements, length(ini_values)); % Matrix for age uncertainties

                    while ik<=length(ini_values)
                        %Age equation
                        z= app.Sr_Sr_measured-ini_values(ik);
                        ages_measurement= (1/lambda)*log(1+ z./app.Rb_Sr_measured);
                        % Store ages in the corresponding column (starting from column 3)
                        result_matrix(:, ik) = ages_measurement;
                        %Age error calculation
                        % Partial derivatives
                        partial_t_D = 1 ./ (lambda * (z + app.Rb_Sr_measured));
                        partial_t_P = -z ./ (lambda * app.Rb_Sr_measured .* (z + app.Rb_Sr_measured));
                        % Error propagation
                        sigma_t_squared = (partial_t_D.^2 .* app.Sr_Sr_measured_err.^2) + (partial_t_P.^2 .* app.Rb_Sr_measured_err.^2) + 2 .*partial_t_D.*partial_t_P.*app.rho.*app.Sr_Sr_measured_err.*app.Rb_Sr_measured_err;
                        sigma_t = sqrt(sigma_t_squared);
                        uncertainty_matrix(:, ik) = sigma_t; % Store uncertainties
%                         %disp(ages_measurement);
%                         greater_strat= ages_measurement>strat_age;
%                         c= sum(greater_strat(:)==1);
%                         c_L= [c_L; c];
                        ik= ik+1;
                    end
                    % Display the result matrix
                    disp('Result Matrix (Rb_Sr, Sr_Sr, Ages):');
                    disp(result_matrix);
                    
                    % Now create a matrix for coloring (1 if age > strat_age, 0 otherwise)
                    strat_age_with_err = strat_age + strat_age_err;
                    color_matrix = result_matrix(:, 1:end) <= strat_age_with_err;

                    % Filter out ages with uncertainty > 10% of strat_age for probability calculation
%                     uncertainty_threshold = 0.1 * strat_age; % 10% of stratigraphic age

                    % Initialize a matrix to track valid ages (1 if the age is considered, 0 otherwise)
%                     valid_ages_matrix = uncertainty_matrix <= uncertainty_threshold;

                    % Update the color_matrix to exclude ages with high uncertainty
%                     filtered_color_matrix = color_matrix & valid_ages_matrix; % Only consider valid ages for probability

                    % Calculate probabilities of ages being greater than strat_age for each ini_value
                    probabilities_u = sum(color_matrix, 1) / n_measurements; % Unfiltered probabilities
                    probabilities_unfiltered= probabilities_u./sum(probabilities_u);
%                     probabilities_f = sum(filtered_color_matrix, 1) ./ sum(valid_ages_matrix, 1); % Proportion of valid ages > strat_age
%                     probabilities_filtered= probabilities_f./sum(probabilities_f);

                    % Plot the result matrix with color coding and uncertainty matrix below it
                    figure;
                    
                    % Plot heatmap for age comparison (first matrix)
                    subplot(3, 1, 1); % First subplot for heatmap
                    imagesc(ini_values, 1:n_measurements, color_matrix); % Use unsorted matrix
                    colormap([1 0 0 ; 0 1 0]); % Red for ages > strat_age, green otherwise
                    colorbar;
                    xlabel('Initial Sr/Sr Values)');
                    ylabel('Measurement Index');
                    title('Ages Lower or equal than Stratigraphic Age');


                    % Plot heatmap for age uncertainties (second matrix)
                    subplot(3, 1, 2); % Second subplot for uncertainty heatmap
                    imagesc(ini_values, 1:n_measurements, uncertainty_matrix); % Use unsorted uncertainty matrix
                    
                    % Create custom colormap from dark green to dark red
                    n_colors = 256; % Number of color steps
                    custom_colormap = [linspace(0, 1, n_colors)', linspace(1, 0, n_colors)', linspace(0, 0, n_colors)']; % Dark green to dark red
                    
                    % Apply the custom colormap and display the colorbar
                    colormap(gca, custom_colormap);
                    colorbar;
                    
                    % Set the color limits to correspond to the min and max uncertainty values
                    % Get min and max uncertainty values
                    min_uncertainty = min(uncertainty_matrix(:));
                    max_uncertainty = max(uncertainty_matrix(:));
                    caxis([min_uncertainty max_uncertainty]);
                    
                    % Add labels and title for uncertainty heatmap
                    xlabel('Initial Sr/Sr Values');
                    ylabel('Measurement Index');
                    title('Age Uncertainties');
                    
                    % Add colorbar label for min and max uncertainty values
                    cb = colorbar;
                    ylabel(cb, 'age-err (Ma)'); % Label for the colorbar
                    cb.Ticks = [min_uncertainty max_uncertainty]; % Set the ticks to show only min and max
                    cb.TickLabels = {num2str(min_uncertainty), num2str(max_uncertainty)}; % Display actual min/max values
                    
                    % Plot the probabilities in a new subplot (third subplot)
                    subplot(3, 1, 3); % Third subplot for probabilities
                    bar(ini_values, probabilities_unfiltered, 'FaceColor', 'blue', 'EdgeColor', 'none');
                    hold on;

                    % Plot the filtered probabilities as a transparent bar
%                     bar(ini_values, probabilities_filtered, 'FaceColor', 'red', 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                    
                    xlabel('Initial Sr/Sr Values');
                    ylabel('Probability of Age > Strat Age');
                    title('Probability of Ages Greater than Stratigraphic Age');
                    %ylim([0, 1]); % Set y-axis limit to reflect probability scale (0 to 1)
                    %legend({'Unfiltered'});
%                     legend({'Unfiltered', 'Filtered (age-err <= 5% strat age)'});
                    hold off;

%                     %determine absoulte probability of Sr/Sr initials
%                     total= sum(c_L);
%                     p_c_L= c_L./total;
                    
                    plot(app.UIAxes3,least_most_radiogenic,probabilities_unfiltered,'r-')
                    drawnow
                    pause(0.5);
                %%%%%%%%%%%%%%% NUMBER OF ITERATIONS - STATISTICAL APPROACH %%%%%%%%%%%%%%%                
                    %absolute probability of Sr/Sr initials      
                    prob_ini_allowed_L= probabilities_unfiltered;
                    least_most_radiogenic= least_most_radiogenic.';
                    
                    % Initialize variables
                    max_iterations = length(least_most_radiogenic);
                    critical_value_factor = 1.36;
                    p_value_threshold = 0.05; % 95% confidence level
                    
                    % Initialize storage
                    all_ages1 = [];
                    all_ages2 = [];
                    iteration_counts = [];
                    
                    % Loop until p-value exceeds critical value
                    p_value = 1;
                    critical_value = 2;
                        while p_value < critical_value
                            % Run first batch of simulations
                            [ages1, age_errs1] = fcns_RbSrapp.calculateModelAges(max_iterations, lambda, prob_ini_allowed_L, least_most_radiogenic,app.rho,app.Rb_Sr_measured,app.Rb_Sr_measured_err,app.Sr_Sr_measured, app.Sr_Sr_measured_err);
                            
                            % Run second batch of simulations
                            [ages2, age_errs2] = fcns_RbSrapp.calculateModelAges(max_iterations, lambda, prob_ini_allowed_L, least_most_radiogenic,app.rho,app.Rb_Sr_measured,app.Rb_Sr_measured_err,app.Sr_Sr_measured, app.Sr_Sr_measured_err);
                            
                            % Combine results
                            all_ages1 = [all_ages1; [ages1, age_errs1]];
                            all_ages2 = [all_ages2; [ages2, age_errs2]];
                            
                            % Perform Kolmogorov-Smirnov test
                            [~, p_value, ~] = kstest2(all_ages1(:, 1), all_ages2(:, 1));
                            
                            % Calculate critical value
                            num_ages1 = length(all_ages1(:, 1));
                            num_ages2 = length(all_ages2(:, 1));
                            critical_value = critical_value_factor * sqrt((num_ages1 + num_ages2) / (num_ages1 * num_ages2));
                            critical_value = critical_value;
                            p_value = p_value;
                            % Display current iteration results
                            disp(['Critical Value: ', num2str(critical_value)]);
                            disp(['P-Value: ', num2str(p_value)]);
                            
                            % Update iteration count
                            iteration_counts = [iteration_counts; size(all_ages1, 1)];
                        end
                    
                    % Display final iteration count
                    disp(['Number of iterations until p-value exceeded critical value: ', num2str(iteration_counts(end)/length(all_ages2))]);
                    % Remove rows where the age values are negative after concatenation
                    all_ages2 = all_ages2(all_ages2(:, 1) >= 0, :);  % Keep only rows where the age is non-negative
                    app.allages=[];
                    app.allages= all_ages2(:,1);
                    app.allages_err=[];
                    app.allages_err= all_ages2(:,2);
                    % Filter age uncertainty <= 5% strat age
                    %all_ages_err_filtered= all_ages2(all_ages2(:, 2) <= 0.05*strat_age, :);
                    %Filter ages <= stratigraphic age
                    %all_ages_filtered= all_ages2(all_ages2(:, 1) <= strat_age, :);
%                         end
%                         
%                         %how many iterations until p value exceeds critical
%                         %value
%                         R_L= [R_L; runs];
%                         %store the last two age distributionmodelages
%                         Mallages= [Mallages; Mmage3_filtered2; Mmage4_filtered2]; 
%                         app.allages= Mallages(:,1);
%                         app.allages_err= Mallages(:,2);
%                         %set the modelageerrors to 10Ma if they are less
%                         %than 10 Ma
%                         app.allages_err(app.allages_err < 10) = 10;
                      
%                     end
                    pause(0.5);
                    % t represents the x-axis values [Ma]
                    t= 0:1:4500;
                    % steps is the x-axis plotting point, every 1Myr
                    steps= 1;
                    A_sum_ft4= fcns_RbSrapp.pdp(t,steps,app.allages,app.allages_err);
                    %A_sum_ft_filtered_err= fcns_RbSrapp.pdp(t,steps,all_ages_err_filtered(:,1),all_ages_err_filtered(:,2));
                    %A_sum_ft_filtered_age= fcns_RbSrapp.pdp(t,steps,all_ages_filtered(:,1),all_ages_filtered(:,2));
                    % Plot the lines with varying shades of red
                    pdline5 = plot(app.UIAxes4, t, A_sum_ft4, 'Color', [1, 0, 0], 'LineWidth', 1);  % Bright Red
                    hold(app.UIAxes4, 'on');
                    %pdline6 = plot(app.UIAxes4, t, A_sum_ft_filtered_err, 'Color', [0.8, 0, 0], 'LineWidth', 1);  % Darker Red
                    %pdline7 = plot(app.UIAxes4, t, A_sum_ft_filtered_age, 'Color', [0.6, 0, 0], 'LineWidth', 1);  % Even Darker Red
                    xline(app.UIAxes4,strat_age,"k-",{"stratigraphic age"})
                    xlim(app.UIAxes4,[0, 4500]);
                    % Add a legend with descriptions
                    %legend(app.UIAxes4, {'Unfiltered', 'Filtered age-err <= 5% strat age', 'Filtered age <= strat age'}, 'Location', 'northeast');
                    drawnow

                    hold(app.UIAxes4,'off')
                    %how often the dataset went through the iteration
                    it= iteration_counts(end)/max_iterations;
                    %Get the 95th percentile of the iterations
                    R_L_percentile= prctile((iteration_counts./max_iterations), 95);
                    %Iteration stats
                    resultstring= num2str(R_L_percentile);
                    %show the value of the 95th percentile in the app
                    %interface
                    app.iterations95thpercentileTextArea.Value = resultstring;
                    
                    delete(wb);
%%%%%%%%%%%%%%% NUMBER OF ITERATIONS - USER INPUT %%%%%%%%%%%%%%%%%%%%%%%%%                
                elseif (strcmpi(app.modelafterSwitch.Value,'A')) && (strcmpi(app.optimizeiterationSwitch.Value,'Off'))
                    %get initial Sr isotope range
                    %Take each value from min to max Sr isotope value in
                    %0.001 steps
                    ini_values = least_radiogenic:0.001:most_radiogenic;
                    %Generate an empty list
                    c_L=[];
                    %initiate while loop and let it run until the length of
                    %Sr isotope vector is reached
                    ik= 1;
                    % Number of measured data points
                    n_measurements = length(app.Rb_Sr_measured);
                    % Initialize matrix for results
                    result_matrix = zeros(n_measurements, length(ini_values)); % First 2 columns for measured values, others for ages
                    uncertainty_matrix = zeros(n_measurements, length(ini_values)); % Matrix for age uncertainties

                    while ik<=length(ini_values)
                        %Age equation
                        z= app.Sr_Sr_measured-ini_values(ik);
                        ages_measurement= (1/lambda)*log(1+ z./app.Rb_Sr_measured);
                        % Store ages in the corresponding column (starting from column 3)
                        result_matrix(:, ik) = ages_measurement;
                        %Age error calculation
                        % Partial derivatives
                        partial_t_D = 1 ./ (lambda * (z + app.Rb_Sr_measured));
                        partial_t_P = -z ./ (lambda * app.Rb_Sr_measured .* (z + app.Rb_Sr_measured));
                        % Error propagation
                        sigma_t_squared = (partial_t_D.^2 .* app.Sr_Sr_measured_err.^2) + (partial_t_P.^2 .* app.Rb_Sr_measured_err.^2) + 2 .*partial_t_D.*partial_t_P.*app.rho.*app.Sr_Sr_measured_err.*app.Rb_Sr_measured_err;
                        sigma_t = sqrt(sigma_t_squared);
                        uncertainty_matrix(:, ik) = sigma_t; % Store uncertainties
%                         %disp(ages_measurement);
%                         greater_strat= ages_measurement>strat_age;
%                         c= sum(greater_strat(:)==1);
%                         c_L= [c_L; c];
                        ik= ik+1;
                    end
                    % Display the result matrix
                    disp('Result Matrix (Rb_Sr, Sr_Sr, Ages):');
                    disp(result_matrix);
                    
                    % Now create a matrix for coloring (1 if age > strat_age, 0 otherwise)
                    strat_age_with_err = strat_age + strat_age_err;
                    color_matrix = result_matrix(:, 1:end) <= strat_age_with_err;

                    % Filter out ages with uncertainty > 5% of strat_age for probability calculation
%                     uncertainty_threshold = 0.1 * strat_age; % 5% of stratigraphic age

                    % Initialize a matrix to track valid ages (1 if the age is considered, 0 otherwise)
%                     valid_ages_matrix = uncertainty_matrix <= uncertainty_threshold;

                    % Update the color_matrix to exclude ages with high uncertainty
%                     filtered_color_matrix = color_matrix & valid_ages_matrix; % Only consider valid ages for probability

                    % Calculate probabilities of ages being greater than strat_age for each ini_value
                    probabilities_u = sum(color_matrix, 1) / n_measurements; % Unfiltered probabilities
                    probabilities_unfiltered= probabilities_u./sum(probabilities_u);
%                     probabilities_f = sum(filtered_color_matrix, 1) ./ sum(valid_ages_matrix, 1); % Proportion of valid ages > strat_age
%                     probabilities_filtered= probabilities_f./sum(probabilities_f);

                    % Plot the result matrix with color coding and uncertainty matrix below it
                    figure;
                    
                    % Plot heatmap for age comparison (first matrix)
                    subplot(3, 1, 1); % First subplot for heatmap
                    imagesc(ini_values, 1:n_measurements, color_matrix); % Use unsorted matrix
                    colormap([1 0 0 ; 0 1 0]); % Red for ages > strat_age, green otherwise
                    colorbar;
                    xlabel('Initial Sr/Sr Values)');
                    ylabel('Measurement Index');
                    title('Ages Lower or equal than Stratigraphic Age');
      
                    % Plot heatmap for age uncertainties (second matrix)
                    subplot(3, 1, 2); % Second subplot for uncertainty heatmap
                    imagesc(ini_values, 1:n_measurements, uncertainty_matrix); % Use unsorted uncertainty matrix
                    
                    % Create custom colormap from dark green to dark red
                    n_colors = 256; % Number of color steps
                    custom_colormap = [linspace(0, 1, n_colors)', linspace(1, 0, n_colors)', linspace(0, 0, n_colors)']; % Dark green to dark red
                    
                    % Apply the custom colormap and display the colorbar
                    colormap(gca, custom_colormap);
                    colorbar;
                    
                    % Set the color limits to correspond to the min and max uncertainty values
                    % Get min and max uncertainty values
                    min_uncertainty = min(uncertainty_matrix(:));
                    max_uncertainty = max(uncertainty_matrix(:));
                    caxis([min_uncertainty max_uncertainty]);
                    
                    % Add labels and title for uncertainty heatmap
                    xlabel('Initial Sr/Sr Values');
                    ylabel('Measurement Index');
                    title('Age Uncertainties');
                    
                    % Add colorbar label for min and max uncertainty values
                    cb = colorbar;
                    ylabel(cb, 'age-err (Ma)'); % Label for the colorbar
                    cb.Ticks = [min_uncertainty max_uncertainty]; % Set the ticks to show only min and max
                    cb.TickLabels = {num2str(min_uncertainty), num2str(max_uncertainty)}; % Display actual min/max values
                    
                    % Plot the probabilities in a new subplot (third subplot)
                    subplot(3, 1, 3); % Third subplot for probabilities
                    bar(ini_values, probabilities_unfiltered, 'FaceColor', 'blue', 'EdgeColor', 'none');
                    hold on;

                    % Plot the filtered probabilities as a transparent bar
%                     bar(ini_values, probabilities_filtered, 'FaceColor', 'red', 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                    
                    xlabel('Initial Sr/Sr Values');
                    ylabel('Probability of Age > Strat Age');
                    title('Probability of Ages Greater than Stratigraphic Age');
                    %ylim([0, 1]); % Set y-axis limit to reflect probability scale (0 to 1)
                    %legend({'Unfiltered'});
%                     legend({'Unfiltered', 'Filtered (age-err <= 5% strat age)'});
                    hold off;

%                     %determine absoulte probability of Sr/Sr initials
%                     total= sum(c_L);
%                     p_c_L= c_L./total;
                    
                    plot(app.UIAxes3,least_most_radiogenic,probabilities_unfiltered,'r-')
                    drawnow
                    pause(0.5);
                    %%%%%%%%%%%%%%%%%%
                    %absolute probability of Sr/Sr initials      
                    prob_ini_allowed_L= probabilities_unfiltered;
                    least_most_radiogenic= least_most_radiogenic.';
                    %Get iteration value
                    max_iterations= length(least_most_radiogenic)*app.numberofiterationsEditField.Value;
                    %Apply iteration
                    [ages3, age_errs3] = fcns_RbSrapp.calculateModelAges(max_iterations, lambda, prob_ini_allowed_L, least_most_radiogenic,app.rho,app.Rb_Sr_measured,app.Rb_Sr_measured_err,app.Sr_Sr_measured, app.Sr_Sr_measured_err);
                    % Combine results
                    all_ages3 = [ages3, age_errs3];
                    % Remove rows where the age values are negative after concatenation
                    all_ages3 = all_ages3(all_ages3(:, 1) >= 0, :);  % Keep only rows where the age is non-negative
                    app.allages= all_ages3(:,1);
                    app.allages_err= all_ages3(:,2);
                    % Filter age uncertainty <= 5% strat age
                    %all_ages_err_filtered= all_ages3(all_ages3(:, 2) <= 0.05*strat_age, :);
                    %Filter ages <= stratigraphic age
                    %all_ages_filtered= all_ages3(all_ages3(:, 1) <= strat_age, :);

                    pause(0.5);
                    % t represents the x-axis values [Ma]
                    t= 0:1:4500;
                    % steps is the x-axis plotting point, every 1Myr
                    steps= 1;
                    A_sum_ft4= fcns_RbSrapp.pdp(t,steps,app.allages,app.allages_err);
                    %A_sum_ft_filtered_err= fcns_RbSrapp.pdp(t,steps,all_ages_err_filtered(:,1),all_ages_err_filtered(:,2));
                    %A_sum_ft_filtered_age= fcns_RbSrapp.pdp(t,steps,all_ages_filtered(:,1),all_ages_filtered(:,2));
                    % Plot the lines with varying shades of red
                    pdline5 = plot(app.UIAxes4, t, A_sum_ft4, 'Color', [1, 0, 0], 'LineWidth', 1);  % Bright Red
                    hold(app.UIAxes4, 'on');
                    %pdline6 = plot(app.UIAxes4, t, A_sum_ft_filtered_err, 'Color', [0.8, 0, 0], 'LineWidth', 1);  % Darker Red
                    %pdline7 = plot(app.UIAxes4, t, A_sum_ft_filtered_age, 'Color', [0.6, 0, 0], 'LineWidth', 1);  % Even Darker Red
                    xline(app.UIAxes4,strat_age,"k-",{"stratigraphic age"})
                    xlim(app.UIAxes4,[0, 4500]);
                    % Add a legend with descriptions
                    %legend(app.UIAxes4, {'Unfiltered', 'Filtered age-err <= 5% strat age', 'Filtered age <= strat age'}, 'Location', 'northeast');
                    drawnow
                    hold(app.UIAxes4,'off')

                    %Iteration 
                    resultstring= '';
                    %show the value of the 95th percentile in the app
                    %interface
                    app.iterations95thpercentileTextArea.Value = resultstring;
                    
                    delete(wb);
                %%%%%%%%%%%%%%% PROBABILITY %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else
                f = uifigure;
                uialert(f,'enter a value > 0','invalid value','CloseFcn',@(h,e) close(f))
                end
            end


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            app.iterations95thpercentileTextArea.Value= '';
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %user chooses storage folder directory
            storagePath = uigetdir(); 
            % Check if the base result folder exists
            if ~exist(storagePath, 'dir')
                % If it doesn't exist, create it
                mkdir(storagePath);
            end
            % Find existing result folders
            existingFolders = dir(fullfile(storagePath, 'MAD_RbSr_Results_*'));
            existingFolderNumbers = cellfun(@(x) str2double(x(end)), {existingFolders.name});
            % Determine the next folder number
            if isempty(existingFolderNumbers)
                nextFolderNumber = 1;
            else
                nextFolderNumber = max(existingFolderNumbers) + 1;
            end
            % Create the new result folder
            resultFolder = fullfile(storagePath, ['MAD_RbSr_Results_' num2str(nextFolderNumber)]);
            mkdir(resultFolder);
            % Create the filenames
            filename1= fullfile(resultFolder, 'modelage_regression.pdf');
            filename2= fullfile(resultFolder, 'modelage_regression.xlsx');
            filename3= fullfile(resultFolder, 'modelage_modelled.pdf');
            filename4= fullfile(resultFolder, 'modelage_modelled.xlsx');
            % Save the outputs you want to store into the folder
            exportgraphics(app.UIAxes2, filename1,"ContentType","vector");
            writematrix([app.age_reg,app.age_reg_err], filename2);
            exportgraphics(app.UIAxes4, filename3,"ContentType","vector");
            writematrix([app.allages,app.allages_err], filename4);
        end

        % Value changed function: iterations95thpercentileTextArea
        function iterations95thpercentileTextAreaValueChanged(app, event)
            app.iterationstat = app.iterations95thpercentileTextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MADRbSr = uifigure('Visible', 'off');
            app.MADRbSr.Name = 'MADRb/Sr';
            app.MADRbSr.Icon = 'Untitled4.jpg';
            app.MADRbSr.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MADRbSr);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Data overview')
            xlabel(app.UIAxes, '87Rb/86Sr')
            ylabel(app.UIAxes, '87Sr/86Sr')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Ages from regression  ')
            xlabel(app.UIAxes2, 'age (Ma)')
            ylabel(app.UIAxes2, 'probability')
            app.ClearselectionButton = uibutton(app.GridLayout2, 'push');
            app.ClearselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearselectionButtonPushed, true);
            app.ClearselectionButton.Text = 'Clear selection';
            app.UpdateButton = uibutton(app.GridLayout2, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.yinterceptLabel = uilabel(app.GridLayout2);
            app.yinterceptLabel.Text = 'y-intercept / uncertainty:';
            app.yinterceptuncertaintyEditField_3 = uieditfield(app.GridLayout2, 'numeric');
            app.InputfilenamexlsxEditField = uieditfield(app.GridLayout2, 'text');
            app.InputfilenamexlsxEditFieldLabel = uilabel(app.GridLayout2);
            app.InputfilenamexlsxEditFieldLabel.Text = 'Input (filename.xlsx)';
            app.MSWDEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MSWDEditField.ValueChangedFcn = createCallbackFcn(app, @MSWDEditFieldValueChanged, true);
            app.MSWDEditField.Editable = 'off';
            app.MSWDLabel = uilabel(app.GridLayout2);
            app.MSWDLabel.Text = 'MSWD:';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.LoaddataButton = uibutton(app.GridLayout2, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.yinterceptuncertaintyEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.yinterceptuncertaintyEditField_2.ValueChangedFcn = createCallbackFcn(app, @yinterceptuncertaintyEditField_2ValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, 'Probability allowed Sr initials')
            xlabel(app.UIAxes3, '87Sr/86Sr (initial Sr) ')
            ylabel(app.UIAxes3, 'probability')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout3);
            title(app.UIAxes4, 'Model ages')
            xlabel(app.UIAxes4, 'model age (Ma)')
            ylabel(app.UIAxes4, 'probability')
            zlabel(app.UIAxes4, 'Z')
            app.optimizeiterationSwitchLabel = uilabel(app.GridLayout3);
            app.optimizeiterationSwitchLabel.Text = 'optimize iteration:';
            app.optimizeiterationSwitch = uiswitch(app.GridLayout3, 'slider');
            app.optimizeiterationSwitch.ValueChangedFcn = createCallbackFcn(app, @optimizeiterationSwitchValueChanged, true);
            app.ClearButton = uibutton(app.GridLayout3, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ageMaEditField = uieditfield(app.GridLayout3, 'numeric');
            app.stratigraphicageMaLabel = uilabel(app.GridLayout3);
            app.stratigraphicageMaLabel.Text = 'age (Ma):';
            app.mostradiogenicEditField = uieditfield(app.GridLayout3, 'numeric');
            app.mostradiogenicLabel = uilabel(app.GridLayout3);
            app.mostradiogenicLabel.Text = 'most radiogenic:';
            app.RunButton = uibutton(app.GridLayout3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExportButton = uibutton(app.GridLayout3, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.leastradiogenicLabel = uilabel(app.GridLayout3);
            app.leastradiogenicLabel.Text = 'least radiogenic:';
            app.leastradiogenicEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.numberofiterationsLabel = uilabel(app.GridLayout3);
            app.numberofiterationsLabel.Text = 'number of iterations:';
            app.numberofiterationsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.modelafterSwitchLabel = uilabel(app.GridLayout3);
            app.modelafterSwitchLabel.Text = 'model after:';
            app.modelafterSwitch = uiswitch(app.GridLayout3, 'slider');
            app.modelafterSwitch.Items = {'D', 'A'};
            app.modelafterSwitch.Value = 'D';
            app.iterations95thpercentileTextAreaLabel = uilabel(app.GridLayout3);
            app.iterations95thpercentileTextAreaLabel.Text = 'iterations 95th percentile';
            app.iterations95thpercentileTextArea = uitextarea(app.GridLayout3);
            app.iterations95thpercentileTextArea.ValueChangedFcn = createCallbackFcn(app, @iterations95thpercentileTextAreaValueChanged, true);
            app.iterations95thpercentileTextArea.Editable = 'off';
            app.ageuncertaintyMaEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ageuncertaintyMaEditFieldLabel = uilabel(app.GridLayout3);
            app.ageuncertaintyMaEditFieldLabel.Text = 'age uncertainty (Ma):';
            app.StratigraphicageLabel = uilabel(app.GridLayout3);
            app.StratigraphicageLabel.Text = 'Stratigraphic age:';
            app.RadiogenicrangeLabel = uilabel(app.GridLayout3);
            app.RadiogenicrangeLabel.Text = 'Radiogenic range:';
            app.DetritalDAuthigenicAmodelLabel = uilabel(app.GridLayout3);
            app.DetritalDAuthigenicAmodelLabel.Text = 'Detrital D / Authigenic A model:';
            app.IterationmethodLabel = uilabel(app.GridLayout3);
            app.IterationmethodLabel.Text = 'Iteration method:';
            % Show the figure after all components are created
            app.MADRbSr.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RbSrappTest_autoreflowv17

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MADRbSr)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MADRbSr)
        end
    end
end